classdef AudioDecryptor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SmoresAudioPlayerUIFigure  matlab.ui.Figure
        SmoresAppLabel             matlab.ui.control.Label
        StopButton                 matlab.ui.control.Button
        PauseButton                matlab.ui.control.Button
        PlayButton                 matlab.ui.control.Button
        PickAudioButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        file_path % Description
        y
        Fs
        player
        name
        duration
        playButtonPushed
%         intervalKey
%         musicEncrypted
        numIntervals
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if (app.playButtonPushed == 0)
                app.player = audioplayer(app.y, app.Fs);
                app.playButtonPushed = 1;
                play(app.player);
            elseif (app.playButtonPushed == 1)
                resume(app.player);
            elseif (app.playButtonPushed == 2)
                stop(app.player);
                app.playButtonPushed = 0;
                return;
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            pause(app.player);
        end

        % Button pushed function: PickAudioButton
        function PickAudioButtonPushed(app, event)
             app.PickAudioButton.Text = "Loading...";
            
            [file, path] = uigetfile('/Users/qayyuma/Downloads/*.smores');
            
            if (file == 0)
                return
            end
            
            filename = fullfile(path,file);
            load(filename,'-mat','intervalKey', 'musicEncrypted', 'numIntervals', 'Fs');
            app.Fs = Fs;


            % [intervalKey,musicEncrypted,numIntervals,app.Fs]

            app.PickAudioButton.Text = 'Decrypting...'
            
            [musicSplit] = musicDecrypter(musicEncrypted, intervalKey);
            [app.y] = musicReassembler(musicSplit, numIntervals);
    
            app.PickAudioButton.Text = 'Done!'


            app.playButtonPushed = 0;
            app.file_path = [path, file];
            app.name = file;
%             [app.y, app.Fs] = audioread(app.file_path);
            app.player = audioplayer(app.y, app.Fs);

            app.PickAudioButton.Text = 'Pick Audio';

            function [musicSplit] = musicDecrypter(musicEncrypted, intervalKey)

            [a, b] = size(musicEncrypted);
            
            musicSplit = zeros(a,b);
            
            for i = 1:a
                for j = 1:b
                    musicSplit(intervalKey(i),j) = musicEncrypted(i,j);
                end
            end
            end
            
            function [music] = musicReassembler(musicSplit, numIntervals)
            
            [a,b] = size(musicSplit);
            musicLength = a*b;
            music = zeros(1,musicLength);
            count = 1;
            for i = 1:numIntervals
                for j = 1:(floor(musicLength/numIntervals))
                    bruh = musicSplit(i,j);
                    music(count) = bruh;
                    count = count + 1;
                end
            end
            
            end
        end

        % Button pushed function: StopButton
        function StopButtonPressed(app, event)
            app.playButtonPushed = 0;
            stop(app.player);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SmoresAudioPlayerUIFigure = uifigure('Visible', 'off');
            app.SmoresAudioPlayerUIFigure.Name = 'S''mores Audio Player';
            app.PickAudioButton = uibutton(app.SmoresAudioPlayerUIFigure, 'push');
            app.PickAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PickAudioButtonPushed, true);
            app.PickAudioButton.Text = 'Pick Audio';
            app.PlayButton = uibutton(app.SmoresAudioPlayerUIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.SmoresAudioPlayerUIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.SmoresAudioPlayerUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPressed, true);
            app.StopButton.Text = 'Stop';
            app.SmoresAppLabel = uilabel(app.SmoresAudioPlayerUIFigure);
            app.SmoresAppLabel.Text = 'S''mores App';
            % Show the figure after all components are created
            app.SmoresAudioPlayerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AudioDecryptor

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SmoresAudioPlayerUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.SmoresAudioPlayerUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SmoresAudioPlayerUIFigure)
        end
    end
end